classdef Administrator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SetVerdictTab                   matlab.ui.container.Tab
        SelectCandidateListBox_2        matlab.ui.control.ListBox
        SelectCandidateListBox_2Label   matlab.ui.control.Label
        SetStatusButtonGroup            matlab.ui.container.ButtonGroup
        GOButton                        matlab.ui.control.ToggleButton
        NOGOButton                      matlab.ui.control.ToggleButton
        ConfirmButton                   matlab.ui.control.Button
        ManualRescheduleTab             matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        SelectRoundDropDown             matlab.ui.control.DropDown
        SelectRoundDropDownLabel        matlab.ui.control.Label
        SelectSlotDropDown              matlab.ui.control.DropDown
        SelectSlotDropDownLabel         matlab.ui.control.Label
        SelectInterviewerDropDown       matlab.ui.control.DropDown
        SelectInterviewerDropDownLabel  matlab.ui.control.Label
        SelectCandidateListBox          matlab.ui.control.ListBox
        SelectCandidateListBoxLabel     matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
        ViewCandidatesTab               matlab.ui.container.Tab
        UITableStudents                 matlab.ui.control.Table
        ViewScheduleTab                 matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        AdminTab                        matlab.ui.container.Tab
        InitialScheduleButton           matlab.ui.control.Button
        LogoutButton                    matlab.ui.control.Button
        WelcomenullptrLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        timerObj % Timer object for updating the table
    end
    
    methods (Access = private)
        function setupTimer(app)
            % Create the timer object and set its properties
            app.timerObj = timer('ExecutionMode', 'fixedRate', 'Period', 5, 'TimerFcn', @(~,~)updateTable(app));
        end
    
        function updateTable(app)
            % Load the MAT file and read the table data
            load('info.mat');
            %app.UITable.Data = interviewers;
            app.UITable.Data = timing;
            app.UITableStudents.Data = candidates;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, loginPage)
            setupTimer(app);
            start(app.timerObj);
            app.WelcomenullptrLabel.Text = "Welcome "+loginPage.loginName;
            load('info.mat','candidates','timing');
            app.UITable.Data=timing;
            app.UITableStudents.Data=candidates;

            temp2=candidates.id(candidates.selected==2)';
            if(size(temp2)>0)
                app.SelectCandidateListBox_2.Items=string(temp2);                       %%%%% Items must be 1D array
            else
                app.SelectCandidateListBox_2.Items="";
            end


        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            %stop(app.timerObj);
            UIFigureCloseRequest(app, event)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.timerObj);
            delete(app.timerObj);
            delete(app)
        end

        % Button pushed function: InitialScheduleButton
        function InitialScheduleButtonPushed(app, event)
            scheduling(1);
            uialert(app.UIFigure,'Successful','Success');
        end

        % Clicked callback: SelectCandidateListBox_2
        function SelectCandidateListBox_2Clicked(app, event)
            item = event.InteractionInformation.Item;
            if item
                app.GOButton.Enable="on";
                app.NOGOButton.Enable="on";
                app.ConfirmButton.Enable="on";
            else
                app.GOButton.Enable="off";
                app.NOGOButton.Enable="off";
                app.ConfirmButton.Enable="off";
            end
        end

        % Selection changed function: SetStatusButtonGroup
        function SetStatusButtonGroupSelectionChanged(app, event)
            selectedButton = app.SetStatusButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'NOGO'
                    app.ConfirmButton.Enable="on";
                case 'GO'
                    app.ConfirmButton.Enable="on";
            end
        end

        % Button down function: SetStatusButtonGroup
        function SetStatusButtonGroupButtonDown(app, event)
            switch app.SetStatusButtonGroup.SelectedObject.Text
                case 'NOGO'
                    app.ConfirmButton.Enable="on";
                case 'GO'
                    app.ConfirmButton.Enable="on";
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            load('info.mat','candidates','timing','interviewers');
            
            app.GOButton.Enable="off";
            app.NOGOButton.Enable="off";

            cid=app.SelectCandidateListBox_2.Value;
            cid=str2num(cid);
            switch app.SetStatusButtonGroup.SelectedObject.Text
                case 'NOGO'
                    candidates{cid,'selected'}=0;
                case 'GO'
                    candidates{cid,'selected'}=1;
            end
            
            %Refresh listbox
            temp2=candidates.id(candidates.selected==2)';
            if(size(temp2)>0)
                app.SelectCandidateListBox_2.Items=string(temp2);                       %%%%% Items must be 1D array
            else
                app.SelectCandidateListBox_2.Items="";
            end

            save('info.mat','candidates','-append');
            scheduling(3);
            uialert(app.UIFigure,'Successful!','Status message');
        end

        % Button down function: ManualRescheduleTab
        function ManualRescheduleTabButtonDown(app, event)
            load('info.mat','candidates','timing','interviewers');
            temp2=candidates.id(candidates.selected==2)';
            if(size(temp2)>0)
                app.SelectCandidateListBox.Items=string(temp2);                       %%%%% Items must be 1D array
            else
                app.SelectCandidateListBox.Items="";
            end
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            load('info.mat','candidates','interviewers','timing');
            cid=app.SelectCandidateListBox.Value;
            cid=str2num(cid);
            tslot=app.SelectSlotDropDown.Value;
            tslot=str2num(tslot);
            iid=app.SelectInterviewerDropDown.Value;
            iid=str2num(iid);

            inttrack=interviewers{iid,"track"};
            if inttrack{1}(8)=='1'
                idx1=2;
            elseif inttrack{1}(9)=='1'
                idx1=3;
            else
                idx1=1;
            end
            if candidates{cid,"interviewed"}{1}(idx1)=='0'
                if candidates{cid,"scheduled"}{1}(idx1)=='0'
                    uialert(app.UIFigure,'Another round is pending!','Error');
                else
                    if isnan(timing{iid,tslot+1})
                        check=interviewers{iid,'slots'}{1}(tslot);
                        a=(check=='1');
                        if interviewers{iid,'slots'}{1}(tslot)=='1'
                            sameslotid=app.SelectInterviewerDropDown.Items;
                            sameslotid=str2double(sameslotid);
                            flag=0;
                            for i=sameslotid
                                if flag==1
                                    break
                                end
                                for j=1:18
                                    if ~isnan(timing{i,j})
                                        if timing{i,j}==cid
                                            timing{i,j}=NaN;
                                            save('info.mat','timing','-append');
                                            flag=1;
                                            break
                                        end
                                    end
                                end
                            end
                            timing{iid,tslot+1}=cid;
                            save('info.mat','timing','-append');
                            uialert(app.UIFigure,'Successful!','Success');
                        else
                            uialert(app.UIFigure,'Interviewer is not available!','Error');
                        end
                    else
                        uialert(app.UIFigure,'Interviewer is not free!','Error');
                    end
                end
            else
                uialert(app.UIFigure,'Candidate has completed interview for that round!','Error');
            end
        end

        % Value changed function: SelectRoundDropDown
        function SelectRoundDropDownValueChanged(app, event)
            load('info.mat','interviewers');
            idx=app.SelectRoundDropDown.Value;
            idx=str2num(idx);
            if idx==0
                uialert(app.UIFigure,'Select a round!','Error');
                return;
            end

            temp=[];
            for i=1:size(interviewers,1)
                if interviewers.track{i}(8)=='1'
                    if idx==2
                        temp=[temp,string(interviewers.id(i))];
                    end
                elseif interviewers.track{i}(9)=='1'
                    if idx==3
                        temp=[temp,string(interviewers.id(i))];
                    end
                else
                    if idx==1
                        temp=[temp,string(interviewers.id(i))];
                    end
                end
            end
            if size(temp)>0
                app.SelectInterviewerDropDown.Items=temp;
            else
                app.SelectInterviewerDropDown.Items="";
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            app.Panel.Position = [(app.UIFigure.Position(3)) / 2 - 325, (app.UIFigure.Position(4)) / 2 - 281, 670, 550
                ];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.WelcomenullptrLabel = uilabel(app.Panel);
            app.WelcomenullptrLabel.Text = 'Welcome nullptr';
            app.LogoutButton = uibutton(app.Panel, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.TabGroup = uitabgroup(app.Panel);
            app.AdminTab = uitab(app.TabGroup);
            app.AdminTab.Title = 'Admin';
            app.InitialScheduleButton = uibutton(app.AdminTab, 'push');
            app.InitialScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @InitialScheduleButtonPushed, true);
            app.InitialScheduleButton.Text = 'Initial Schedule';
            app.ViewScheduleTab = uitab(app.TabGroup);
            app.ViewScheduleTab.Title = 'View Schedule';
            app.UITable = uitable(app.ViewScheduleTab);
            app.UITable.ColumnName = {'InterviewerID'; '9:00-9:30'; '9:30-10:00'; '10:00-10:30'; '10:30-11:00'; '11:00-11:30'; '11:30-12:00'; '12:00-12:30'; '12:30-13:00'; '13:00-13:30'; '13:30-14:00'; '14:00-14:30'; '14:30-15:00'; '15:00-15:30'; '15:30-16:00'; '16:00-16:30'; '16:30-17:00'; '17:00-17:30'; '17:30-18:00'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.ViewCandidatesTab = uitab(app.TabGroup);
            app.ViewCandidatesTab.Title = 'View Candidates';
            app.UITableStudents = uitable(app.ViewCandidatesTab);
            app.UITableStudents.ColumnName = {'id'; 'name'; 'track'; 'Resume'; 'scheduled'; 'interviewed'; 'selected'};
            app.UITableStudents.RowName = {};
            app.UITableStudents.ColumnSortable = true;
            app.ManualRescheduleTab = uitab(app.TabGroup);
            app.ManualRescheduleTab.Title = 'Manual Reschedule';
            app.ManualRescheduleTab.ButtonDownFcn = createCallbackFcn(app, @ManualRescheduleTabButtonDown, true);
            app.GridLayout = uigridlayout(app.ManualRescheduleTab);
            app.SubmitButton = uibutton(app.GridLayout, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.SelectCandidateListBoxLabel = uilabel(app.GridLayout);
            app.SelectCandidateListBoxLabel.Text = 'Select Candidate';
            app.SelectCandidateListBox = uilistbox(app.GridLayout);
            app.SelectCandidateListBox.Items = {};
            app.SelectCandidateListBox.Value = {};
            app.SelectInterviewerDropDownLabel = uilabel(app.GridLayout);
            app.SelectInterviewerDropDownLabel.Text = 'Select Interviewer';
            app.SelectInterviewerDropDown = uidropdown(app.GridLayout);
            app.SelectInterviewerDropDown.Items = {};
            app.SelectInterviewerDropDown.Value = {};
            app.SelectSlotDropDownLabel = uilabel(app.GridLayout);
            app.SelectSlotDropDownLabel.Text = 'Select Slot';
            app.SelectSlotDropDown = uidropdown(app.GridLayout);
            app.SelectSlotDropDown.Items = {'09:00 - 09:30', '09:30 - 10:00', '10:00 - 10:30', '10:30 - 11:00', '11:00 - 11:30', '11:30 - 12:00', '12:00 - 12:30', '12:30 - 13:00', '13:00 - 13:30', '13:30 - 14:00', '14:00 - 14:30', '14:30 - 15:00', '15:00 - 15:30', '15:30 - 16:00', '16:00 - 16:30', '16:30 - 17:00', '17:00 - 17:30', '17:30 - 18:00'};
            app.SelectSlotDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.SelectSlotDropDown.Value = '1';
            app.SelectRoundDropDownLabel = uilabel(app.GridLayout);
            app.SelectRoundDropDownLabel.Text = 'Select Round';
            app.SelectRoundDropDown = uidropdown(app.GridLayout);
            app.SelectRoundDropDown.Items = {'Select', 'Tech', 'HR', 'Man'};
            app.SelectRoundDropDown.ItemsData = {'0', '1', '2', '3'};
            app.SelectRoundDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectRoundDropDownValueChanged, true);
            app.SelectRoundDropDown.Value = '0';
            app.SetVerdictTab = uitab(app.TabGroup);
            app.SetVerdictTab.Title = 'Set Verdict';
            app.ConfirmButton = uibutton(app.SetVerdictTab, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Confirm';
            app.SetStatusButtonGroup = uibuttongroup(app.SetVerdictTab);
            app.SetStatusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SetStatusButtonGroupSelectionChanged, true);
            app.SetStatusButtonGroup.Title = 'Set Status';
            app.SetStatusButtonGroup.ButtonDownFcn = createCallbackFcn(app, @SetStatusButtonGroupButtonDown, true);
            app.NOGOButton = uitogglebutton(app.SetStatusButtonGroup);
            app.NOGOButton.Enable = 'off';
            app.NOGOButton.Text = 'NOGO';
            app.NOGOButton.Value = true;
            app.GOButton = uitogglebutton(app.SetStatusButtonGroup);
            app.GOButton.Enable = 'off';
            app.GOButton.Text = 'GO';
            app.SelectCandidateListBox_2Label = uilabel(app.SetVerdictTab);
            app.SelectCandidateListBox_2Label.Text = 'Select Candidate';
            app.SelectCandidateListBox_2 = uilistbox(app.SetVerdictTab);
            app.SelectCandidateListBox_2.Items = {};
            app.SelectCandidateListBox_2.ClickedFcn = createCallbackFcn(app, @SelectCandidateListBox_2Clicked, true);
            app.SelectCandidateListBox_2.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Administrator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end