classdef Interviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterviewerPageUIFigure        matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        MyInterviewsTab                matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        SetAvailabilityTab             matlab.ui.container.Tab
        SelectAvailTSListBox           matlab.ui.control.ListBox
        SelectavailabletimeslotsListBoxLabel  matlab.ui.control.Label
        UpdateAvailTSButton            matlab.ui.control.Button
        SetVerdictTab                  matlab.ui.container.Tab
        ConfirmButton                  matlab.ui.control.Button
        PendingCandidatesListBox       matlab.ui.control.ListBox
        PendingCandidatesListBoxLabel  matlab.ui.control.Label
        SetStatusButtonGroup           matlab.ui.container.ButtonGroup
        GOButton                       matlab.ui.control.ToggleButton
        NOGOButton                     matlab.ui.control.ToggleButton
        LogoutButton                   matlab.ui.control.Button
        WelcomenullptrLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        InterviewerId % Description
        timerObj % Timer object for updating the table
    end
    
    methods (Access = private)
        function setupTimer(app)
            % Create the timer object and set its properties
            app.timerObj = timer('ExecutionMode', 'fixedRate', 'Period', 5, 'TimerFcn', @(~,~)updateTable(app));
        end
    
        function updateTable(app)
            % Load the MAT file and read the table data
            load('info.mat','interviewers','timing','candidates');       % TODO: specify variables
            %app.UITable.Data = interviewers;            % got to change
            temp=isnan(timing{app.InterviewerId,:});
            temp=(temp==0);
            temp2=table;
            temp3=timing.Properties.VariableNames(temp);
            time_interval = temp3(2:end)';
            temp4={};
            
            for i=1:size(time_interval)
                if strcmp(time_interval{i}, '9_930')
                    temp4=[temp4;'09:00-09:30'];
    
                elseif strcmp(time_interval{i}, '930_10')
                    temp4=[temp4;'09:30-10:00'];
                
                elseif strcmp(time_interval{i}, '10_1030')
                    temp4=[temp4;'10:00-10:30'];
                
                elseif strcmp(time_interval{i}, '1030_11')
                    temp4=[temp4;'10:30-11:00'];
                
                elseif strcmp(time_interval{i}, '11_1130')
                    temp4=[temp4;'11:00-11:30'];
                
                elseif strcmp(time_interval{i}, '1130_12')
                    temp4=[temp4;'11:30-12:00'];
                
                elseif strcmp(time_interval{i}, '12_1230')
                    temp4=[temp4;'12:00-12:30'];
                
                elseif strcmp(time_interval{i}, '1230_13')
                    temp4=[temp4;'12:30-13:00'];
                
                elseif strcmp(time_interval{i}, '13_1330')
                    temp4=[temp4;'13:00-13:30'];
                
                elseif strcmp(time_interval{i}, '1330_14')
                    temp4=[temp4;'13:30-14:00'];
                
                elseif strcmp(time_interval{i}, '14_1430')
                    temp4=[temp4;'14:00-14:30'];
                
                elseif strcmp(time_interval{i}, '1430_15')
                    temp4=[temp4;'14:30-15:00'];
                
                elseif strcmp(time_interval{i}, '15_1530')
                    temp4=[temp4;'15:00-15:30'];
                
                elseif strcmp(time_interval{i}, '1530_16')
                    temp4=[temp4;'15:30-16:00'];
                
                elseif strcmp(time_interval{i}, '16_1630')
                    temp4=[temp4;'16:00-16:30'];
                
                elseif strcmp(time_interval{i}, '1630_17')
                    temp4=[temp4;'16:30-17:00'];
                
                elseif strcmp(time_interval{i}, '17_1730')
                    temp4=[temp4;'17:00-17:30'];
                
                elseif strcmp(time_interval{i}, '1730_18')
                    temp4=[temp4;'17:30-18:00'];
                
                else
                    % Code block for other cases
                
                end
            end
    
            temp2.TimeSlot = temp4;


            %temp2.TimeSlot=temp3(2:end)';
            temp=timing{app.InterviewerId,:}(temp);
            temp=temp(2:end);
            temp2.ID=temp';
            temp2.Name=candidates.name(temp);
            temp2.Resume=candidates.Resume(temp);
            temp5={};
            stat = candidates.selected(temp);
            for i=1:size(stat)

                if stat == 0
                    temp5=[temp5;'Rejected'];
                elseif stat == 1
                    temp5=[temp5;'Selected'];
                elseif stat ==2 
                    temp5=[temp5;'In Process'];
                end
            end
            temp2.Status=temp5;
                
            %temp2.Status=candidates.selected(temp);
            app.UITable.Data=temp2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, loginPage)
            %app.UITable.Data = loginPage.interviewersTable;     % got to change
            %app.updateTable();
            load('info.mat','candidates','timing','interviewers');
            app.WelcomenullptrLabel.Text = "Welcome "+loginPage.loginName;
            app.InterviewerId = interviewers.id(find(interviewers.name==loginPage.loginName));
            %uialert(app.InterviewerPageUIFigure,num2str(app.InterviewerId),"title")
            setupTimer(app);
            start(app.timerObj);

            temp=isnan(timing{app.InterviewerId,:});
            temp=(temp==0);
            temp=timing{app.InterviewerId,:}(temp);
            temp=temp(2:end);
            temp2=[];
            inttrack=interviewers{app.InterviewerId,"track"};
            if inttrack{1}(8)=='1'
                idx=2;
            elseif inttrack{1}(9)=='1'
                idx=3;
            else
                idx=1;
            end
            for i=temp
                if candidates{i,'interviewed'}{1}(idx)=='0'
                    temp2=[temp2,string(i)];
                end
            end
            if(size(temp2)>0)
                app.PendingCandidatesListBox.Items=temp2;                       %%%%% Items must be 1D array
            else
                app.PendingCandidatesListBox.Items="";
            end
            app.Panel.Position = [(app.InterviewerPageUIFigure.Position(3) - 325) / 2 , (app.InterviewerPageUIFigure.Position(4) - 241) / 2 , 641, 504];
        end

        % Button pushed function: UpdateAvailTSButton
        function UpdateAvailTSButtonPushed(app, event)
            load('info.mat','candidates','interviewers','timing');
            sel = app.SelectAvailTSListBox.Value;
            temp='000000000000000000';
            for i=1:18
                if ismember(num2str(i),sel)
                    temp(i)='1';
                end
            end
            interviewers.slots{app.InterviewerId}=temp;
            slotnames=timing.Properties.VariableNames;
            inttrack=interviewers{app.InterviewerId,"track"};
            if inttrack{1}(8)=='1'
                idx=2;
            elseif inttrack{1}(9)=='1'
                idx=3;
            else
                idx=1;
            end
            for i=1:18
                if strcmp(temp(i),'0')
                    if ~isnan(timing{app.InterviewerId,slotnames(i+1)})
                        candidates{timing{app.InterviewerId,slotnames(i+1)},'scheduled'}{1}(idx)='0';
                        % timing{app.InterviewerId,slotnames(i+1)}=NaN;
                    end
                end
            end

            save('info.mat','candidates','timing','interviewers','-append');
            scheduling2(app.InterviewerId);
            uialert(app.InterviewerPageUIFigure,'Successful','Status message');
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            %stop(app.timerObj);
            InterviewerPageUIFigureCloseRequest(app, event)
        end

        % Close request function: InterviewerPageUIFigure
        function InterviewerPageUIFigureCloseRequest(app, event)
            stop(app.timerObj);
            delete(app.timerObj);
            delete(app)
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            load('info.mat','candidates','timing','interviewers');
            
            app.GOButton.Enable="off";
            app.NOGOButton.Enable="off";
            
            cid=app.PendingCandidatesListBox.Value;
            cid=str2num(cid);
            switch app.SetStatusButtonGroup.SelectedObject.Text
                case 'NOGO'
                    candidates{cid,'selected'}=0;
            end
            inttrack=interviewers{app.InterviewerId,"track"};
            if inttrack{1}(8)=='1'
                idx=2;
            elseif inttrack{1}(9)=='1'
                idx=3;
            else
                idx=1;
            end
            candidates{cid,"interviewed"}{1}(idx)='1';

            switch app.SetStatusButtonGroup.SelectedObject.Text
                case 'GO'
                    if candidates{cid,'interviewed'}{1}=="111"
                        candidates{cid,'selected'}=1;
                    end
            end

            % Refresh listbox
            temp=isnan(timing{app.InterviewerId,:});
            temp=(temp==0);
            temp=timing{app.InterviewerId,:}(temp);
            temp=temp(2:end);
            temp2=[];
            inttrack=interviewers{app.InterviewerId,"track"};
            if inttrack{1}(8)=='1'
                idx=2;
            elseif inttrack{1}(9)=='1'
                idx=3;
            else
                idx=1;
            end
            for i=temp
                if candidates{i,'interviewed'}{1}(idx)=='0'
                    temp2=[temp2,string(i)];
                end
            end
            if(size(temp2)>0)
                app.PendingCandidatesListBox.Items=temp2;                       %%%%% Items must be 1D array
            else
                app.PendingCandidatesListBox.Items="";
            end

            save('info.mat','candidates','-append');
            scheduling(3);
            uialert(app.InterviewerPageUIFigure,'Successful!','Status message');
        end

        % Button down function: SetStatusButtonGroup
        function SetStatusButtonGroupButtonDown(app, event)
            switch app.SetStatusButtonGroup.SelectedObject.Text
                case 'NOGO'
                    app.ConfirmButton.Enable="on";
                case 'GO'
                    app.ConfirmButton.Enable="on";
            end
        end

        % Clicked callback: PendingCandidatesListBox
        function PendingCandidatesListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            if item
                app.GOButton.Enable="on";
                app.NOGOButton.Enable="on";
                app.ConfirmButton.Enable="on";
            else
                app.GOButton.Enable="off";
                app.NOGOButton.Enable="off";
                app.ConfirmButton.Enable="off";
            end
        end

        % Selection changed function: SetStatusButtonGroup
        function SetStatusButtonGroupSelectionChanged(app, event)
            selectedButton = app.SetStatusButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'NOGO'
                    app.ConfirmButton.Enable="on";
                case 'GO'
                    app.ConfirmButton.Enable="on";
            end
        end

        % Button down function: SetVerdictTab
        function SetVerdictTabButtonDown(app, event)
            load("info.mat","candidates","timing","interviewers")
            temp=isnan(timing{app.InterviewerId,:});
            temp=(temp==0);
            temp=timing{app.InterviewerId,:}(temp);
            temp=temp(2:end);
            temp2=[];
            inttrack=interviewers{app.InterviewerId,"track"};
            if inttrack{1}(8)=='1'
                idx=2;
            elseif inttrack{1}(9)=='1'
                idx=3;
            else
                idx=1;
            end
            for i=temp
                if candidates{i,'interviewed'}{1}(idx)=='0'
                    temp2=[temp2,string(i)];
                end
            end
            if(size(temp2)>0)
                app.PendingCandidatesListBox.Items=temp2;                       %%%%% Items must be 1D array
            else
                app.PendingCandidatesListBox.Items="";
            end
        end

        % Size changed function: InterviewerPageUIFigure
        function InterviewerPageUIFigureSizeChanged(app, event)
            app.Panel.Position = [(app.InterviewerPageUIFigure.Position(3)) / 2 - 325, (app.InterviewerPageUIFigure.Position(4)) / 2 - 241, 641, 504];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterviewerPageUIFigure = uifigure('Visible', 'off');
            app.InterviewerPageUIFigure.Name = 'Interviewer Page';
            app.InterviewerPageUIFigure.CloseRequestFcn = createCallbackFcn(app, @InterviewerPageUIFigureCloseRequest, true);
            app.InterviewerPageUIFigure.SizeChangedFcn = createCallbackFcn(app, @InterviewerPageUIFigureSizeChanged, true);
            app.Panel = uipanel(app.InterviewerPageUIFigure);
            app.WelcomenullptrLabel = uilabel(app.Panel);
            app.WelcomenullptrLabel.Text = 'Welcome nullptr';
            app.LogoutButton = uibutton(app.Panel, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.TabGroup = uitabgroup(app.Panel);
            app.MyInterviewsTab = uitab(app.TabGroup);
            app.MyInterviewsTab.Title = 'My Interviews';
            app.UITable = uitable(app.MyInterviewsTab);
            app.UITable.ColumnName = {'Time Slot'; 'ID'; 'Name'; 'Resume'; 'Status'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.SetAvailabilityTab = uitab(app.TabGroup);
            app.SetAvailabilityTab.Title = 'Set Availability';
            app.UpdateAvailTSButton = uibutton(app.SetAvailabilityTab, 'push');
            app.UpdateAvailTSButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAvailTSButtonPushed, true);
            app.UpdateAvailTSButton.Text = 'Update';
            app.SelectavailabletimeslotsListBoxLabel = uilabel(app.SetAvailabilityTab);
            app.SelectavailabletimeslotsListBoxLabel.Text = 'Select available time slots';
            app.SelectAvailTSListBox = uilistbox(app.SetAvailabilityTab);
            app.SelectAvailTSListBox.Items = {'09:00 - 09:30', '09:30 - 10:00', '10:00 - 10:30', '10:30 - 11:00', '11:00 - 11:30', '11:30 - 12:00', '12:00 - 12:30', '12:30 - 13:00', '13:00 - 13:30', '13:30 - 14:00', '14:00 - 14:30', '14:30 - 15:00', '15:00 - 15:30', '15:30 - 16:00', '16:00 - 16:30', '16:30 - 17:00', '17:00 - 17:30', '17:30 - 18:00'};
            app.SelectAvailTSListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.SelectAvailTSListBox.Multiselect = 'on';
            app.SelectAvailTSListBox.Tooltip = {'Use control select to multiselect available time slots'};
            app.SelectAvailTSListBox.Value = {'1'};
            app.SetVerdictTab = uitab(app.TabGroup);
            app.SetVerdictTab.Title = 'Set Verdict';
            app.SetVerdictTab.ButtonDownFcn = createCallbackFcn(app, @SetVerdictTabButtonDown, true);
            app.SetStatusButtonGroup = uibuttongroup(app.SetVerdictTab);
            app.SetStatusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SetStatusButtonGroupSelectionChanged, true);
            app.SetStatusButtonGroup.Title = 'Set Status';
            app.SetStatusButtonGroup.ButtonDownFcn = createCallbackFcn(app, @SetStatusButtonGroupButtonDown, true);
            app.NOGOButton = uitogglebutton(app.SetStatusButtonGroup);
            app.NOGOButton.Enable = 'off';
            app.NOGOButton.Text = 'NOGO';
            app.NOGOButton.Value = true;
            app.GOButton = uitogglebutton(app.SetStatusButtonGroup);
            app.GOButton.Enable = 'off';
            app.GOButton.Text = 'GO';
            app.PendingCandidatesListBoxLabel = uilabel(app.SetVerdictTab);
            app.PendingCandidatesListBoxLabel.Tooltip = {'Select to set status of candidates'};
            app.PendingCandidatesListBoxLabel.Text = 'Pending Candidates';
            app.PendingCandidatesListBox = uilistbox(app.SetVerdictTab);
            app.PendingCandidatesListBox.Items = {};
            app.PendingCandidatesListBox.ClickedFcn = createCallbackFcn(app, @PendingCandidatesListBoxClicked, true);
            app.PendingCandidatesListBox.Value = {};
            app.ConfirmButton = uibutton(app.SetVerdictTab, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.InterviewerPageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interviewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterviewerPageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterviewerPageUIFigure)
        end
    end
end