classdef loginPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MathWorksInterviewPortalUIFigure  matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        LoginButton             matlab.ui.control.Button
        PasswordEditField       matlab.ui.control.EditField
        PasswordEditFieldLabel  matlab.ui.control.Label
        UsernameEditField       matlab.ui.control.EditField
        UsernameEditFieldLabel  matlab.ui.control.Label
        InterviewLabel          matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    
    properties (Access = public)
        candidatesTable % Description
        interviewersTable
        timingTable
        loginName
        org_password
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('info.mat');
            app.candidatesTable = candidates;
            app.interviewersTable = interviewers;
            app.timingTable = timing;
            
            app.Panel.Position = [(app.MathWorksInterviewPortalUIFigure.Position(3) - 409) / 2 , (app.MathWorksInterviewPortalUIFigure.Position(4) - 319) / 2 , 409, 311];
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            userName = app.UsernameEditField.Value;
            passWord = app.PasswordEditField.Value;
            if userName==""
                uialert(app.MathWorksInterviewPortalUIFigure,"Please Enter Username","Login Failed");
            elseif passWord==""
                uialert(app.MathWorksInterviewPortalUIFigure,"Please Enter Password","Login Failed");
            elseif userName=="admin" && passWord=="abcd123"
                    %show admin side
                    %uialert(app.MathWorksInterviewPortalUIFigure,"show admin side","success");
                    app.loginName=userName;
                    app.UsernameEditField.Value = "";
                    app.PasswordEditField.Value = "";
                    Administrator(app)
            elseif any(app.interviewersTable.name==userName & app.interviewersTable.password==passWord)
                %show interviwer side
                %uialert(app.MathWorksInterviewPortalUIFigure,"show intreviewer side","success");
                app.loginName=userName;
                app.UsernameEditField.Value = "";
                app.PasswordEditField.Value = "";
                Interviewer(app)
            else
                %error
                uialert(app.MathWorksInterviewPortalUIFigure,"Invalid Credentials","Login Failed");
                app.UsernameEditField.Value = "";
                app.PasswordEditField.Value = "";
            end
        end

        % Size changed function: MathWorksInterviewPortalUIFigure
        function MathWorksInterviewPortalUIFigureSizeChanged(app, event)
            app.Panel.Position = [(app.MathWorksInterviewPortalUIFigure.Position(3) - 409) / 2, (app.MathWorksInterviewPortalUIFigure.Position(4) - 319) / 2, 409, 311];
        end

        % Value changing function: PasswordEditField
        function PasswordEditFieldValueChanging(app, event)
            if numel(event.Value)==0
                return
            end
            temp=event.Value(end);
            if strcmp(temp, 'return')
                LoginButtonPushed(app, event);
            end
            % if temp=='*'
            %     app.org_password(end)=[];
            %     return
            % end
            % app.org_password = [app.org_password,temp];
            % hiddenpass=event.Value;
            % hiddenpass(end)='*';
            % app.PasswordEditField.Value=hiddenpass;            
        end

        % Key press function: MathWorksInterviewPortalUIFigure
        function MathWorksInterviewPortalUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(event.Key, 'return')
                LoginButtonPushed(app, event);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MathWorksInterviewPortalUIFigure = uifigure('Visible', 'off');
            app.MathWorksInterviewPortalUIFigure.Name = 'MathWorks Interview Portal';
            app.MathWorksInterviewPortalUIFigure.SizeChangedFcn = createCallbackFcn(app, @MathWorksInterviewPortalUIFigureSizeChanged, true);
            app.MathWorksInterviewPortalUIFigure.KeyPressFcn = createCallbackFcn(app, @MathWorksInterviewPortalUIFigureKeyPress, true);
            app.Panel = uipanel(app.MathWorksInterviewPortalUIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MW.png');
            app.InterviewLabel = uilabel(app.Panel);
            app.InterviewLabel.Text = 'Interview';
            app.UsernameEditFieldLabel = uilabel(app.Panel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.Panel, 'text');
            app.PasswordEditFieldLabel = uilabel(app.Panel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.Panel, 'text');
            app.PasswordEditField.ValueChangingFcn = createCallbackFcn(app, @PasswordEditFieldValueChanging, true);
            app.LoginButton = uibutton(app.Panel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            % Show the figure after all components are created
            app.MathWorksInterviewPortalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loginPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MathWorksInterviewPortalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MathWorksInterviewPortalUIFigure)
        end
    end
end